classdef nel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Nel_Main                 matlab.ui.Figure
        Menu_File                matlab.ui.container.Menu
        Menu_new                 matlab.ui.container.Menu
        Menu_New_track           matlab.ui.container.Menu
        Menu_New_unit            matlab.ui.container.Menu
        Menu_Edit                matlab.ui.container.Menu
        Menu_Edit_track          matlab.ui.container.Menu
        Menu_Edit_unit           matlab.ui.container.Menu
        Menu_File_cd             matlab.ui.container.Menu
        Convert2Mac              matlab.ui.container.Menu
        Menu_Save_pic            matlab.ui.container.Menu
        Menu_Open                matlab.ui.container.Menu
        Menu_Exit                matlab.ui.container.Menu
        Menu_Block               matlab.ui.container.Menu
        Menu_Stimulus            matlab.ui.container.Menu
        Menu_Gating              matlab.ui.container.Menu
        Menu_Mix                 matlab.ui.container.Menu
        Menu_Acq_Params          matlab.ui.container.Menu
        Menu_Pulse_Params        matlab.ui.container.Menu
        Menu_nel_tmplts          matlab.ui.container.Menu
        MenuToggleAcqParams      matlab.ui.container.Menu
        MenuTogglePulseParams    matlab.ui.container.Menu
        Menu_run_stop            matlab.ui.container.Menu
        Menu_Tools               matlab.ui.container.Menu
        Menu_Tools_search        matlab.ui.container.Menu
        Menu_Tools_TC            matlab.ui.container.Menu
        Menu_Tools_calibrate     matlab.ui.container.Menu
        Menu_Tools_calibrateOLD  matlab.ui.container.Menu
        Menu_Tools_CAP           matlab.ui.container.Menu
        Menu_Tools_DPOAE         matlab.ui.container.Menu
        Menu_Tools_WBMEMR        matlab.ui.container.Menu
        Menu_Tools_AdvOAE        matlab.ui.container.Menu
        Menu_Tools_FPL           matlab.ui.container.Menu
        Menu_Channels            matlab.ui.container.Menu
        Menu_Ch_set_number       matlab.ui.container.Menu
        Menu_Ch_bring2front      matlab.ui.container.Menu
        Menu_Ch_rearrange        matlab.ui.container.Menu
        Menu_Ch_closeall         matlab.ui.container.Menu
        Menu_Log                 matlab.ui.container.Menu
        Menu_Log_Error           matlab.ui.container.Menu
        Error_LB                 matlab.ui.control.ListBox
        PulseInfoStatic          matlab.ui.control.Label
        Triggering_title         matlab.ui.control.Label
        Inloop_title             matlab.ui.control.Label
        Template_title           matlab.ui.control.Label
        Template_popup           matlab.ui.control.DropDown
        Template_next            matlab.ui.control.Button
        Template_prev            matlab.ui.control.Button
        Gating_browser           matlab.ui.control.ListBox
        Gating_title             matlab.ui.control.Label
        Mix_title                matlab.ui.control.Label
        Template_ShortName       matlab.ui.control.EditField
        Template_ShorName_LBL    matlab.ui.control.Label
        Mix_browser              matlab.ui.control.ListBox
        Inloop_browser           matlab.ui.control.ListBox
        ShortCuts_BG_dummy       matlab.ui.control.Button
        Go_PB                    matlab.ui.control.Button
        Stop_PB                  matlab.ui.control.Button
        Unit_info_lbl            matlab.ui.control.Label
        Comment_title            matlab.ui.control.Label
        Comment                  matlab.ui.control.TextArea
        Triggering_popup         matlab.ui.control.DropDown
        Unit_Info                matlab.ui.control.Label
        Status_Block_info        matlab.ui.control.Label
        Status_Line_info         matlab.ui.control.Label
        SaveDlg_Frame            matlab.ui.control.Button
        NoSaveData_PB            matlab.ui.control.Button
        SaveDlg_txt              matlab.ui.control.Label
        New_Unit_PB              matlab.ui.control.Button
        Search_PB                matlab.ui.control.Button
        Tuning_Curve_PB          matlab.ui.control.Button
        AcqInfoStatic            matlab.ui.control.Label
        AcqInfo                  matlab.ui.control.Label
        PulseInfo                matlab.ui.control.Label
        CAP_PB                   matlab.ui.control.Button
        DPOAE_PB                 matlab.ui.control.Button
        Inhibit_PB               matlab.ui.control.Button
        SaveData_PB              matlab.ui.control.Button
        WBMEMR_PB                matlab.ui.control.Button
        AdvOAE_PB                matlab.ui.control.Button
        FPL_PB                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        function Initialize(app)
            global NelData
            
            beep on;
            if (NelData.run_mode ~= 0)
                resp = questdlg('Nel is currently collecting data. Are you sure you want to abort and restart Nel?', 'Nel Init', ...
                    'Yes','No','No');
                if (strcmp(resp,'No'))
                    return;
                end
                if (ishandle(NelData.General.main_handle))
                    delete(NelData.General.main_handle)
                end
            end
            NelData.run_mode = 0;
            %fig = openfig(mfilename,'reuse');
            fig = gcf;
            NelData.General.main_handle = fig;
            % Generate a structure of handles to pass to callbacks, and store it.
            handles = guihandles(fig);
            guidata(fig, handles);
            if (Get_User_Info(app,fig,handles)) % check for abort request
                return;
            end
            
%             if (promptMetadata()) % check for abort request
%                 return;
%             end
            
            Refresh_Template_List(app,handles); % TODO: include the user templates (NelData.User_block_templates)
            Set_Menu_Accelerators(app,handles);
            Set_Global_Handles_Lists(app,handles);
            %     tdtinit(fig); % SP on Aug 12, 2020
            if (isempty(NelData.UnSaved))
                set(handles.Menu_Save_pic,'Enable','off');
            end
            set(handles.Menu_Open,'Enable','off'); % Untill this option is implemented
            set(fig,'Visible','on');
            set(NelData.General.main_handle,'handlevisib','on'); % SP added on Aug 12, 2020
            tdtinit(NelData.General.main_handle);
            
            if nargout > 0
                varargout{1} = fig;
            end
           
        end
        
        function Bring2Front(app, hfig, h)
            chld = get(hfig,'Children');
            ind = find(chld == h);
            chld = chld([ind 1:ind-1 ind+1:end]);
            set(hfig,'Children',chld);
        end
        
%         function create_expinfo_gui()
%             fig=figure('Position',[200,200,400,300]);
%             uicontrol('style','text','String','User Name:','Position',[50,240,80,20]);
%             user=uicontrol('style','Edit','Position',[140,240,200,20]);
%         end
        
        
        function abort = Get_User_Info(app, hfig, handles)
            global NelData home_dir SKIPintro
            user = {''}; title = 'NEL Login'; abort = 0;
            while ((isempty(user) | isempty(user{1})))
            
                if SKIPintro
                    user = {'MH'};
                else
                  
 %Old way of prompting
                    expInfo_prompt = {'User Name:'};
                    expInfo = inputdlg(expInfo_prompt,title);
                    user = {expInfo{1}};

%                     expInfo_prompt = {'User Name:','Additional Experimenters:', 'Animal ID:','Sex:','Exposure Type:', 'Exposure Date:', 'Branch:','Comments:'};
%                     expInfo = inputdlg(expInfo_prompt,title);
%                     user = {expInfo{1}};
%                     % Assign to NEL Metadata
%                     NelData.Metadata.User = expInfo{1};
%                     NelData.Metadata.Otherusers = expInfo{2};
%                     NelData.Metadata.ChinID = expInfo{3};
%                     NelData.Metadata.Sex = expInfo{4};
%                     NelData.Metadata.Exposure = expInfo{5};
%                     NelData.Metadata.ExposureDate=expInfo{6};
%                     NelData.Metadata.Branch=expInfo{7};
%                     NelData.Metadata.Comments=expInfo{8};
%                     
%                     expInfo_prompt2 = {'Category of Sedation'};
%                     sedation_opts = {'Light','Ket+Xyl','Awake'};
%                     expInfo2 = listdlg('PromptString',expInfo_prompt2,'SelectionMode','single','ListString',sedation_opts,'ListSize',[160,100]);
%                     NelData.Metadata.Sedation = sedation_opts{expInfo2};       
                end
                if (isempty(user))
                    Nel_Main_CloseRequestFcn(handles.Nel_Main, [], handles,{});
                    if (~ishandle(hfig))
                        abort = 1;
                        return;
                    end
                end
                title = 'Please enter NON-EMPTY user name';
            end
            
            NelData.Metadata.User=user{1};
            
            if (strcmp(NelData.General.User,user{1}))   % this reactivate is just to set the defualt; real decision is made inside choose data dir function.
                 reactivate= 'Yes';
            else
                reactivate = 'No';
            end
            NelData.General.User = user{1};
            user_profile_load(user{1});
            activate_data_dir(app, handles,reactivate);
            if (exist([home_dir 'Users\' user{1}],'dir') ~= 0)
                addpath([home_dir 'Users\' user{1}]);
                if (exist('my_startup','file') == 2)
                    my_startup; % Allow people to update the path and to init their own stuff.
                    % Especially, the user should register his/her templates using 'register_user_templates'
                end
            end
            if (user_profile_get('use_user_templates_tag'))
                switch2user_templates(app, handles.Menu_nel_tmplts,handles);
            else
                switch2nel_templates(app, handles.Menu_nel_tmplts,handles);
            end
        end
        
        function varargout = Menu_Tools_Inhibit_Callback(app, h, eventdata, handles, varargin)
            global NelData
            if (NelData.File_Manager.unit.No == 0)
                nelerror(app, 'Can''t collect data for Unit #0. Open new Unit first');
                return;
            end
            external_run_checkin(app, handles);
            h_inhibit = inhibit_curve;
            if strcmp(NelData.inhibit.rc,'stopNOSAVE')
                uiwait(h_inhibit);
            end
            update_nel_title(app, handles);
            Update_Unit_Info(app, handles);
            
            NelData = rmfield(NelData,'inhibit');
            external_run_checkout(app, handles);
        end
        
        function Refresh_Template_List(app, handles)
            global NelData
            templates = fieldnames(NelData.Block_templates);
            if (isempty(templates))
                nelwarn('No templates defined');
            end
            set(handles.Template_popup,'Items',templates);
            set(handles.Template_popup, 'ItemsData',1:length(templates));
            Update_Selected_Template(app, handles);
        end
        
        function Save_collected_data(app, handles, block_info, stim_info, saved_errors)
            global NelData spikes Trigger
            figure(handles.Nel_Main);
            [~, short_fname] = current_data_file(block_info.short_description);
            set_PB_enable(app, handles.SaveData_PB,'on');
            set_PB_enable(app, handles.NoSaveData_PB,'on');
            set([handles.SaveData_PB handles.NoSaveData_PB handles.SaveDlg_txt handles.SaveDlg_Frame] ,'Visible', 'on');
            set(handles.Menu_run_stop,'Enable','off');
            set([handles.Stop_PB handles.Go_PB handles.ShortCuts_BG_dummy],'Visible','off');
            
            if (get(handles.Triggering_popup,'Value') == 1)
                for ii = 1:2
                    set(handles.Triggering_popup,'BackgroundColor','r');
                    drawnow
                    if (ii <2)
                        neltimer(0.1); %Fixed M.Sayles. 20/11/15
                        %          neltimer(0.1);
                        set(handles.Triggering_popup,'BackgroundColor','w');
                        drawnow
                        neltimer(0.1); %Fixed M.Sayles. 20/11/15
                        %          neltimer(0.1);
                    end
                end
            end
            % Present save/nosave question while allowing the user to edit the comment and triggering
            set(handles.SaveDlg_txt,'UserData',NaN);
            set(handles.SaveDlg_txt,'Text',{'Save Data to:'  ['''' short_fname '''?']});
            NelData.run_mode = 3;
            % Wait for answer
            % beep;   % ge modification %% AF 06/11/02: commented out and put at the end of data_acquisition_loop
            waitfor(handles.SaveDlg_txt,'UserData');
            % Prepare trig, comment and errors for save (or back up in NelData.UnSaved struct).
            trig_ind = get(handles.Triggering_popup,'Value');
            trig_str = get(handles.Triggering_popup,'Text');
            comment  = get(handles.Comment,'Text');
            if (exist('saved_errors','var') == 1)
                error_strs = saved_errors;
            else
                error_ud = get(handles.Error_LB,'UserData');
                if (error_ud.index > 0)
                    error_strs = get(handles.Error_LB,'Text');
                else
                    error_strs = '';
                end
            end
            do_save = get(handles.SaveDlg_txt,'UserData');
            if (do_save)
                try
                    make_text_file(block_info,stim_info,comment,trig_str{trig_ind},error_strs);
                    saved = 1;
            
                    %% SP on 8May19
                    if strcmp(block_info.short_description, 'SR')
                        % compute SR
                        % -----------------
                        cdd;
                        temp_data = spikes.times{1}(spikes.times{1}(:,1)~=0, :);
                        nLines= block_info.fully_presented_lines;
                        stimDur= (Trigger.params.StmOn+Trigger.params.StmOff)/1e3; % seconds
                        SRvalue= sum(temp_data(:,1)<=nLines)/nLines/stimDur;
                        rdd;
                        % -----------------
            
                        NelData.File_Manager.unit.SR= SRvalue;
                        %nel('Update_Unit_Info', handles)
                        %toAppDesignerTag
                        nel_App('Update_Unit_Info', handles)
                    end
            
                catch
                    nelerror(app, 'Failed to save data file');
                    saved = 0;
                end
            end
            if (do_save == 0 | saved == 0)
                NelData.UnSaved.block_info = block_info;
                NelData.UnSaved.stim_info  = stim_info;
                NelData.UnSaved.trig_ind   = trig_ind;
                NelData.UnSaved.comment    = comment;
                NelData.UnSaved.error_strs = error_strs;
                set(handles.Menu_Save_pic,'Enable','on');
            else
                NelData.UnSaved = [];
                set(handles.Menu_Save_pic,'Enable','off');
                update_nel_title(app, handles);
            end
            set(handles.Triggering_popup,'BackgroundColor','w');
            set([handles.SaveData_PB handles.NoSaveData_PB handles.SaveDlg_txt handles.SaveDlg_Frame] ,'Visible', 'off');
            set(handles.Menu_run_stop,'Enable','on');
            set([handles.Stop_PB handles.Go_PB handles.ShortCuts_BG_dummy],'Visible','on');
            NelData.run_mode = 0;
        end
        
        function Set_Global_Handles_Lists(app, handles)
            ud = get(handles.Nel_Main,'Userdata');
            ud.stimulus_def_handles   = [ ...
                handles.Inloop_browser ...
                handles.Mix_browser ...
                handles.Template_ShorName_LBL ...
                handles.Template_ShortName ...
                handles.Mix_title ...
                handles.Gating_title ...
                handles.Gating_browser ...
                handles.Template_prev ...
                handles.Template_next ...
                handles.Template_popup ...
                handles.Template_title ...
                handles.Inloop_title ...
                %handles.Template_Frame ...
                ];
            
%             ud.run_status_handles   = [ ...
%                 handles.Status_Line_info ...
%                 handles.Status_Block_info ...
%                 %handles.Status_frame ...
%                 handles.Triggering_popup ...
%                 handles.Comment ...
%                 handles.Comment_title ...
%                 handles.Triggering_title ...
%                 %handles.TrigComment_frame ...
%                 ];
            
            %toAppDesignerTag
            % AS removed 'frame' tags...may be removed in App Designer
            % format????
             ud.run_status_handles   = [ ...
                handles.Status_Line_info ...
                handles.Status_Block_info ...
                handles.Triggering_popup ...
                handles.Comment ...
                handles.Comment_title ...
                handles.Triggering_title ...
                ];
            
            ud.run_inactive_handles = [ ...
                handles.Menu_Mix ...
                handles.Menu_Gating ...
                handles.Menu_Stimulus ...
                ];
            
            ud.run_inactive_PB_handles = [ ...
                handles.Go_PB ...
                handles.Tuning_Curve_PB ...
                handles.DPOAE_PB ...
                handles.Search_PB ...
                handles.New_Unit_PB ...
                handles.CAP_PB ...
                handles.Inhibit_PB ...
                ];
            
            set(handles.Nel_Main,'Userdata',ud);
        end
        
        function Set_Menu_Accelerators(app, handles)
            set(handles.Menu_Stimulus,'Accelerator','p');
            set(handles.Menu_Gating,'Accelerator','g');
            set(handles.Menu_Mix,'Accelerator','m');
            set(handles.Menu_run_stop,'Accelerator','r');
            set(handles.Menu_Tools_search,'Accelerator','f');
        end
        
        function Toggle_Show_AcqInfo(app, handles)
            global NelData
            if (NelData.General.EP.show == 1)
                set(handles.MenuToggleAcqParams,'Label','Hide Acquisition Info');
                set(handles.AcqInfo, 'Visible', 'on');
                set(handles.AcqInfoStatic, 'Visible', 'on');
                Update_AcqInfo(app, handles);
            else
                NelData.General.EP.record = 0;   % force to not record
                set(handles.MenuToggleAcqParams,'Label','Show Acquisition Info');
                set(handles.AcqInfo, 'Visible', 'off');
                set(handles.AcqInfoStatic, 'Visible', 'off');
            end
        end
        
        function Toggle_Show_PulseInfo(app, handles)
            global NelData
            if (NelData.General.Pulse.show == 1)
                set(handles.MenuTogglePulseParams,'Label','Hide Pulse Info');
                set(handles.PulseInfo, 'Visible', 'on');
                set(handles.PulseInfoStatic, 'Visible', 'on');
                Update_PulseInfo(app, handles);
            else
                NelData.General.Pulse.enabled = 0;   % force to not deliver pulse
                set(handles.MenuTogglePulseParams,'Label','Show Pulse Info');
                set(handles.PulseInfo, 'Visible', 'off');
                set(handles.PulseInfoStatic, 'Visible', 'off');
            end
        end
        
        function Update_AcqInfo(app, handles)
            global NelData
            i_ep = 1;   % for now, hard-coded to include only 1 acquisition channel
            str = cell(4,1);
            str{1} = '';
            if (NelData.General.EP(i_ep).record == 0)
                str{2} = 'no';
            else
                str{2} = 'yes';
            end
            str{3} = sprintf('%d', NelData.General.EP(i_ep).start);
            str{4} = sprintf('%d', NelData.General.EP(i_ep).duration);
            if (NelData.General.EP(i_ep).saveALLtrials == 0)
                str{5} = 'no';
            else
                str{5} = 'yes';
            end
            if (NelData.General.EP(i_ep).decimate == 0)
                str{6} = 'no';
            else
                str{6} = 'yes';
            end
            str{7} = sprintf('%d', NelData.General.EP(i_ep).decimateFactor);
            set(handles.AcqInfo,'Text',str);
        end
        
        function Update_PulseInfo(app, handles)
            global NelData
            str = cell(5,1);
            str{1} = '';
            if (NelData.General.Pulse.enabled == 0)
                str{2} = 'no';
            else
                str{2} = 'yes';
            end
            str{3} = sprintf('%d', NelData.General.Pulse.delay);
            str{4} = sprintf('%d', NelData.General.Pulse.nPulses);
            str{5} = sprintf('%d', NelData.General.Pulse.interPulse);
            set(handles.PulseInfo,'Text',str);
        end
        
        function Update_Selected_Template(app, handles)
            global NelData
            set(handles.Template_ShortName,'Value', 'Please Wait...'); drawnow;
            val = get(handles.Template_popup,'Value');
            strings = get(handles.Template_popup,'Items');
            val = min(val,length(strings));
            template_mfile = getfield(NelData.Block_templates,strings{val});
            [DAL vars template units] = Template2DAL(template_mfile);
            set(handles.Inloop_browser,    'Items', struct2str(vars.Inloop,units.Inloop));
            set(handles.Gating_browser,    'Items', struct2str(vars.Gating,units.Gating));
            set(handles.Mix_browser,       'Items', struct2str(vars.Mix,units.Mix));
            Update_Template_shortname(app, DAL,handles);
            handles.template_mfile = template_mfile;
            handles.template = template;
            handles.vars   = vars;
            handles.units  = units;
            NelData.DAL = DAL;
            guidata(handles.Template_popup,handles);
            user_profile_set(handles.template.tag,handles.vars);
        end
        
        function Update_Template_shortname(app, DAL, handles)
            set(handles.Template_ShortName,'Value', DAL.short_description);
            if (isempty(DAL.short_description))
                set(handles.Template_ShortName,'Enable', 'on','BackgroundColor','y');
            else
                set(handles.Template_ShortName,'Enable', 'off','BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function Update_Unit_Info(app, handles)
            global NelData
            str = cell(5,1);
            if (NelData.File_Manager.track.No > 0)
                str{1} = sprintf('%d',NelData.File_Manager.track.No);
            else
                str{1} = '--';
            end
            if (NelData.File_Manager.unit.No > 0)
                str{2} = sprintf('%02d',NelData.File_Manager.unit.No);
            else
                str{2} = '--';
            end
            if (NelData.File_Manager.unit.BF > 0)
                str{3} = sprintf('%1.2f',NelData.File_Manager.unit.BF);
            else
                str{3} = '--';
            end
            if (NelData.File_Manager.unit.Th > 0)
                str{4} = sprintf('%1.1f',NelData.File_Manager.unit.Th);
            else
                str{4} = '--';
            end
            % SP om 8May19: Adding SR as basic param
            if (NelData.File_Manager.unit.SR > 0)
                str{5} = sprintf('%1.1f',NelData.File_Manager.unit.SR);
            else
                str{5} = '--';
            end
            
            set(handles.Unit_Info,'Text',str);
        end
        
        function varargout = act_on_related_handles(app, related_h, cmd, varargin)
            global NelData
            if (isempty(related_h))
                if (isfield(NelData,'Related_Handles') & isstruct(NelData.Related_Handles))
                    related_h = struct2cell(NelData.Related_Handles);
                else
                    return;
                end
            end
            for i = 1:length(related_h)
                if (ishandle(related_h{i}))
                    feval(cmd,related_h{i},varargin{:})
                elseif (iscell(related_h{i}))
                    for j = length(related_h{i}):-1:1
                        if (ishandle(related_h{i}{j}))
                            feval(cmd,related_h{i}{j},varargin{:})
                        end
                    end
                end
            end
        end
        
        function activate_data_dir(app, handles, reactivate)
            global NelData 
            
            dirname = choose_data_dir(reactivate); % choose_data_dir updates NelData and the user-profiles
            update_nel_title(app, handles);
            % set(handles.Nel_Main,'Name',['Nel   -  ''' dirname '''   (' int2str(NelData.File_Manager.picture) ' Saved Pictures)']);
            Update_Unit_Info(app, handles);
            Update_AcqInfo(app, handles);
            Toggle_Show_AcqInfo(app, handles);
            Update_PulseInfo(app, handles);
            Toggle_Show_PulseInfo(app, handles);
        end
        function change_data_dir(app, handles, reactivate)
            global NelData 
            
            dirname = change_data_dir(reactivate); % choose_data_dir updates NelData and the user-profiles
            update_nel_title(app, handles);
            % set(handles.Nel_Main,'Name',['Nel   -  ''' dirname '''   (' int2str(NelData.File_Manager.picture) ' Saved Pictures)']);
            Update_Unit_Info(app, handles);
            Update_AcqInfo(app, handles);
            Toggle_Show_AcqInfo(app, handles);
            Update_PulseInfo(app, handles);
            Toggle_Show_PulseInfo(app, handles);
        end


        function [buff, index] = cat_str_buffer(app, buff, index, strs, tmp_flag, max_len)
            buff(index+1:index+length(strs)) = strs;
            if (tmp_flag == 0)
                index = index + length(strs);
            end
            if (index >= max_len)
                new_start = round(max_len*0.25);
                buff = buff(new_start:end);
                index = index - new_start+1;
            end
        end
        
        function change_fig_height(app, handles, change)
            orig_pos = get(handles.Nel_Main,'Position');
            new_pos = [orig_pos(1) orig_pos(2)+change  orig_pos(3) orig_pos(4)-change];
            %set(handles.Nel_Main,'Visible', 'off');
            set(handles.Nel_Main,'Position',[new_pos]);
            chld = get(handles.Nel_Main,'Children');
            for i = 1:length(chld)
                if (strcmp(get(chld(i),'Type'),'uicontrol'))
                    pos = get(chld(i),'Position');
                    pos(2) = pos(2)+change;
                    set(chld(i),'Position',pos);
                end
            end
        end
        
        function clear_Error_LB(app, h)
            ud = get(h,'UserData');
            strs = get(h,'Text');
            strs(1:ud.index) = cell(ud.index,1);
            strs = cell(1,1);
            ud.index = 0;
            set(h,'Text',strs);
            set(h,'ListboxTop',1);
            set(h,'Userdata',ud);
            set(h,'Visible','off');
            update_error_tooltip(app, h);
        end
        
        function external_run_checkin(app, handles)
            global NelData
            ud = get(handles.Nel_Main,'Userdata');
            set(ud.stimulus_def_handles,'Visible','off');
            change_fig_height(app, handles, 44);
            set(ud.run_inactive_handles,'Enable','off');
            set_PB_enable(app, ud.run_inactive_PB_handles,'inactive');
            set_PB_enable(app, handles.Stop_PB,'inactive');
            set([handles.Menu_File handles.Menu_Block handles.Menu_Channels handles.Menu_Log handles.Menu_run_stop ...
                handles.Menu_Tools] ,'Enable','off');
            NelData.run_mode = 9;
            drawnow
        end
        
        function external_run_checkout(app, handles)
            global NelData

            % close all figures generated by external GUI (all figs
            % excluding main NEL GUI and tdtinit activeX hidden (fig 1)
            try 
                close(NelData.WBMEMR.Fig2close)
            catch   % in case user has closed any or all of these                
            end

            ud = get(handles.Nel_Main,'Userdata');
            NelData.run_mode = 0;
            set([handles.Menu_File handles.Menu_Block handles.Menu_Channels handles.Menu_Log handles.Menu_run_stop ...
                handles.Menu_Tools] ,'Enable','on');
            set(handles.Status_Block_info,'Text','');
            change_fig_height(app, handles, -44)
            set(ud.stimulus_def_handles,'Visible','on');
            set(ud.run_inactive_handles,'Enable','on');
            set_PB_enable(app, ud.run_inactive_PB_handles,'on');
            set_PB_enable(app, handles.Stop_PB,'inactive');
            figure(handles.Nel_Main);
            set([handles.Menu_File handles.Menu_Block handles.Menu_Channels handles.Menu_Log] ,'Enable','on');
        end
        
        function ud = logerror(app, strs, iswarning, ud)
            if (iswarning)
                err_header = [datestr(now) ' - Warning: '];
            else
                err_header = [datestr(now) ' - Error: '];
            end
            strs{1} = [err_header strs{1}];
            [ud.log.str ud.log.index] = cat_str_buffer(app, ud.log.str, ud.log.index, strs,0,ud.log.length);
        end
       
        function varargout = nelerror(app, strs, iswarning, tmp_flag)
            global NelData
            if (~ishandle(NelData.General.main_handle))
                if (iswarning)
                    warndlg(strs, 'Nel Warning');
                else
                    errordlg(strs, 'Nel Error');
                end
                return;
            end
            handles = guidata(NelData.General.main_handle);
            if (exist('iswarning','var') ~= 1)
                iswarning = 0;
            end
            if (exist('tmp_flag','var') ~= 1)
                tmp_flag = 0;
            end
            if (ischar(strs))
                strs = cellstr(strs);
            end
            ud   = get(handles.Error_LB,'Userdata');
            ud   = logerror(app, strs,iswarning,ud);
            strs = textwrap(handles.Error_LB,strs,54);
            buff = get(handles.Error_LB,'Text');
            already_exist = 1;
            tmp_buff = buff;
            for ii = 1:length(strs)
                ind = strmatch(strs(ii), tmp_buff,'exact');
                if isempty(ind)
                    already_exist = 0;
                    break;
                else
                    tmp_buff = tmp_buff(ind+1:end);
                end
            end
            if (already_exist == 0)
                [buff ud.index] = cat_str_buffer(app, buff, ud.index, strs, tmp_flag, ud.length);
                set(handles.Error_LB,'Text',buff);
                set(handles.Error_LB,'ListboxTop',max(1,ud.index-1));
                if (iswarning)
                    set(handles.Error_LB,'ForegroundColor','k')
                else
                    set(handles.Error_LB,'ForegroundColor','r')
                end
                if (strcmp(get(handles.Error_LB,'Visible'),'off'))
                    set(handles.Error_LB,'Visible','on');
                    if (~iswarning)
                        ding;  % MH/GE 12Nov2003 only dings for real error, not warnings!
                    end
                end
            end
            set(handles.Error_LB,'Userdata',ud);
            update_error_tooltip(app, handles.Error_LB);
            if (NelData.run_mode ~= 9) % Quick & dirty. TODO: save search, tc, calibrate figure handles
                % and 'figure(saved_handle)' at the end of the function.
                figure(NelData.General.main_handle); % Ensure that the figure is visible;
            end
        end
        
        function set_PB_enable(app, hs, state)
            for h = hs(:)'
                %ud = get(h,'Userdata');
                switch (state)
                    case {'inactive' 'off'}
                        %cdata = (ud.cdata + ud.bgmat)/2;
                        %cdata(2:2:end,1:2:end,:) = ud.bgmat(2:2:end,1:2:end,:);
                        set(h, 'Enable','off')
                        % set(h,'CData', (ud.cdata + 2.5*ud.bgmat)/3.5);
                        %set(h,'CData', cdata);
            
                    case {'on'}
                        set(h, 'Enable', 'on')
                        %set(h,'CData', ud.cdata);
            
                    otherwise
                        disp(['toggle_PB_enable: state = ' state '. This should not have happend!']);
                end
            end
        end
        
        function spikes_fig_prepare2run(app, h)
            set(h,'Resize',            'off', ...
                'MenuBar',              'none', ...
                'ToolBar',              'none', ...
                'WindowButtonDownFcn',  '', ...
                'WindowButtonUpFcn',    '');
        end
        
        function switch2nel_templates(app, hmenu, handles)
            global NelData
            rc = 1;
            try
                NelData.Block_templates = NelData.General.Nel_Templates;
            catch
                rc = 0;
            end
            if (rc==0)
                nelerror(app, 'Nel Templates are not defined or empty. Check ''nelinit.m''');
                return;
            end
            Refresh_Template_List(app, handles);
            user_profile_set('use_user_templates_tag',0);
            set(hmenu,'checked','on');
        end
        
        function switch2user_templates(app, hmenu, handles)
            global NelData
            rc = 1;
            try
                if (~isempty(NelData.General.User_templates))
                    NelData.Block_templates = NelData.General.User_templates;
                else
                    rc = 0;
                end
            catch
                rc = 0;
            end
            if (rc==0)
                nelerror(app, 'User Templates are not defined or empty.');
                return;
            end
            Refresh_Template_List(app, handles);
            user_profile_set('use_user_templates_tag',1);
            set(hmenu,'checked','off');
        end
        
        function dlg_pos = template_dlg_pos(app, h_browser, handles)
            fig_pos    = get(handles.Nel_Main,'Position');
            browse_pos = get(h_browser, 'Position');
            dlg_pos(1) = browse_pos(1) + fig_pos(1)+0.5;
            dlg_pos(2) = browse_pos(2) + fig_pos(2)+0.5;
            dlg_pos(3) = browse_pos(3)-0.5;
            dlg_pos(4) = browse_pos(4)-0.5;
        end
        
        function update_error_tooltip(app, h)
            headerstr = {'Double-Click to Confirm and Clear Error Window'; ...
                '-----------------------------------------------------------'};
            if (isequal(get(h,'Visible'),'on'))
                strs = get(h,'Text');
                strs = cat(1,headerstr,strs);
            else
                strs = '';
            end
            tooltipstr = char(strs);
            tooltipstr = sprintf('%s', [tooltipstr repmat(char(10),size(tooltipstr,1),1)]');
            set(h,'ToolTipString',tooltipstr);
        end
        
        function update_nel_title(app, handles)
            global data_dir NelData ProgName
            if (strncmp(data_dir,NelData.File_Manager.dirname,length(data_dir)))
                display_dir = strrep(NelData.File_Manager.dirname(length(data_dir)+1:end),'\','');
            else
                display_dir = NelData.File_Manager.dirname;
            end
            set(handles.Nel_Main,'Name',[ProgName '  -  ''' display_dir '''   (' int2str(NelData.File_Manager.picture) ' Saved Pictures)']);
        end
        
        function update_template_vars(app, h_browser, handles, fieldname, title)
            global NelData
            if (~isequal(get(handles.Nel_Main,'SelectionType'),'open'))
                return;
            end
            vars    = handles.vars;
            units   = handles.units;
            dlg_pos = template_dlg_pos(app, h_browser, handles);
            % Allow Template2Dal to update the template before struct2dlg is being called.
            [NelData.DAL handles.vars handles.template handles.units errstr] = Template2DAL(handles.template_mfile,vars,units,fieldname);
            eval(['[vars.' fieldname ' units.' fieldname '] = structdlg(handles.template.IO_def.' fieldname ...
                ', title, handles.vars.' fieldname ',''on'',[],dlg_pos);']);
            [NelData.DAL handles.vars handles.template handles.units errstr] = Template2DAL(handles.template_mfile,vars,units,fieldname);
            while (~isempty(errstr))
                nelwarn(errstr);
                title = [title ': ''' errstr ''''];
                eval(['[vars.' fieldname ' units.' fieldname '] = structdlg(handles.template.IO_def.' fieldname ...
                    ', title, handles.vars.' fieldname ',''on'',[],dlg_pos);']);
                [NelData.DAL handles.vars handles.template handles.units errstr] = Template2DAL(handles.template_mfile,vars,units,fieldname);
            end
            Update_Template_shortname(app, NelData.DAL,handles);
            set(handles.Inloop_browser, 'Text', struct2str(handles.vars.Inloop,handles.units.Inloop));
            set(handles.Gating_browser, 'Text', struct2str(handles.vars.Gating,handles.units.Gating));
            set(handles.Mix_browser, 'Text', struct2str(handles.vars.Mix,handles.units.Mix));
            guidata(h_browser,handles);
            user_profile_set(handles.template.tag,handles.vars);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('nel_App.mat');
            
            % Set component properties that require runtime configuration
            app.Nel_Main.DeleteFcn = createCallbackFcn(app, @Nel_Main_DeleteFcn, true);
            app.Error_LB.UserData = componentData.Error_LB.UserData;
            app.Go_PB.UserData = componentData.Go_PB.UserData;
            app.Stop_PB.UserData = componentData.Stop_PB.UserData;
            app.NoSaveData_PB.UserData = componentData.NoSaveData_PB.UserData;
            app.New_Unit_PB.UserData = componentData.New_Unit_PB.UserData;
            app.Search_PB.UserData = componentData.Search_PB.UserData;
            app.Tuning_Curve_PB.UserData = componentData.Tuning_Curve_PB.UserData;
            app.CAP_PB.UserData = componentData.CAP_PB.UserData;
            app.DPOAE_PB.UserData = componentData.DPOAE_PB.UserData;
            app.WBMEMR_PB.UserData = componentData.WBMEMR_PB.UserData;
            app.Inhibit_PB.UserData = componentData.Inhibit_PB.UserData;
            app.SaveData_PB.UserData = componentData.SaveData_PB.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Initialize(app)
        end

        % Button pushed function: CAP_PB
        function CAP_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %new format for app designer. gets rid of handles, varargin
            Menu_Tools_CAP_Callback(app, eventdata);
        end

        % Menu selected function: Convert2Mac
        function Convert2Mac_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            ud = get(handles.Nel_Main,'Userdata');
            external_run_checkin(app, handles);
            descr{1} = 'Creating Mac-friendly version of data files';
            descr{2} = ['in directory ''' NelData.File_Manager.dirname ''''];
            set(ud.run_status_handles,'Visible','on');
            set(handles.Status_Block_info,'Text',descr);
            dir_traverse(NelData.File_Manager.dirname,'neltext2mat',0,handles.Status_Line_info);
            set(ud.run_status_handles,'Visible','off');
            external_run_checkout(app, handles)
        end

        % Button pushed function: DPOAE_PB
        function DPOAE_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
%             Menu_Tools_DPOAE_Callback(handles.DPOAE_PB, eventdata, handles, varargin);
              Menu_Tools_DPOAE_Callback(app, eventdata);
        end

        % Button pushed function: WBMEMR_PB
        function WBMEMR_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_WBMEMR_Callback(app, eventdata);
        end

        % Button pushed function: Go_PB
        function Go_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.File_Manager.unit.No == 0)
                nelerror(app, 'Can''t collect data for Unit #0. Open new Unit first');
                return;
            end
            NelData.UnSaved = [];
            set(handles.Menu_Save_pic,'Enable','off');
            ud = get(handles.Nel_Main,'Userdata');
            set(ud.stimulus_def_handles,'Visible','off');
            change_fig_height(app, handles, 44);
            set(ud.run_status_handles,'Visible','on');
            set(ud.run_inactive_handles,'Enable','off');
            set_PB_enable(app, ud.run_inactive_PB_handles,'inactive');
            set_PB_enable(app, handles.Stop_PB,'on');
            set(handles.Menu_run_stop,'Label','&Stop','Accelerator','s');
            set([handles.Menu_File handles.Menu_Block handles.Menu_Channels handles.Menu_Log handles.Menu_Tools] ,'Enable','off')
            NelData.Stop_request = 0;
            NelData.run_mode = 2;
            act_on_related_handles(app, [], 'spikes_fig_prepare2run');
            set(handles.Status_Block_info,'Text',NelData.DAL.description);
            clear_Error_LB(app, handles.Error_LB);
            set(handles.Comment,'Text','');
            %%
            try
                if (isfield(NelData.DAL, 'funcName'))  % added by GE 30oct2003 to allow for alternate DAL functions.
                    [block_info,stim_info] = call_user_func(NelData.DAL.funcName, NelData.DAL,NelData.General.nChannels,handles.Status_Line_info);
                else  % call default "data_acquisition_loop".
                    [block_info,stim_info] = data_acquisition_loop(NelData.DAL,NelData.General.nChannels,handles.Status_Line_info);
                end
                not_successful_flag = 0;
            catch
                not_successful_flag = 1;
            end
            set(handles.Menu_run_stop,'Label','&Run','Accelerator','r');
            if (not_successful_flag)
                nelerror(app, ['Error in data_acquisition_loop. Check your parameters (' lasterr ')']);
            else
                Save_collected_data(app, handles,block_info,stim_info);
            end
            NelData.run_mode = 0;
            set(ud.run_status_handles,'Visible','off');
            change_fig_height(app, handles, -44)
            set(ud.stimulus_def_handles,'Visible','on');
            set(ud.run_inactive_handles,'Enable','on');
            set_PB_enable(app, handles.Stop_PB,'inactive');
            set_PB_enable(app, ud.run_inactive_PB_handles,'on');
            figure(handles.Nel_Main);
            act_on_related_handles(app, [], 'set', 'Resize','on','MenuBar','figure','ToolBar','figure')
            set([handles.Menu_File handles.Menu_Block handles.Menu_Channels handles.Menu_Log handles.Menu_Tools] ,'Enable','on');
            set(handles.Status_Block_info,'Text','');
            set(handles.Triggering_popup,'Value',1);
        end

        % Button pushed function: Inhibit_PB
        function Inhibit_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_Inhibit_Callback(app, handles.Inhibit_PB, eventdata, handles, varargin);
        end

        % Menu selected function: Menu_Acq_Params
        function Menu_Acq_Params_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            i_ep = 1;   % for now, hard-coded to include only 1 acquisition channel
            def.record = { {'0','{1}'} };
            def.start = { NelData.General.EP(i_ep).start 'ms' [0 1000] };
            def.duration = { NelData.General.EP(i_ep).duration 'ms' [10 5000] };
            def.saveALLtrials = { {'{0}','1'} };
            def.decimate = { {'{0}','1'} };
            def.decimateFactor = { NelData.General.EP(i_ep).decimateFactor '' [1 100] };
            dflt.record  = NelData.General.EP(i_ep).record;
            dflt.saveALLtrials = NelData.General.EP(i_ep).saveALLtrials;
            dflt.decimate = NelData.General.EP(i_ep).decimate;
            dflt = structdlg(def,'Acquisition Setup', dflt);
            NelData.General.EP(i_ep).record = dflt.record;
            NelData.General.EP(i_ep).start = dflt.start;
            NelData.General.EP(i_ep).duration = dflt.duration;
            NelData.General.EP(i_ep).saveALLtrials = dflt.saveALLtrials;
            NelData.General.EP(i_ep).decimate = dflt.decimate;
            NelData.General.EP(i_ep).decimateFactor = dflt.decimateFactor;
            Update_AcqInfo(app, handles);
        end

        % Menu selected function: Menu_Ch_bring2front
        function Menu_Ch_bring2front_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            act_on_related_handles(app, [], 'figure');
        end

        % Menu selected function: Menu_Ch_closeall
        function Menu_Ch_closeall_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            act_on_related_handles(app, [], 'delete');
        end

        % Menu selected function: Menu_Ch_rearrange
        function Menu_Ch_rearrange_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            spikes_fig('rearrange');
            act_on_related_handles(app, [], 'figure');
        end

        % Menu selected function: Menu_Ch_set_number
        function Menu_Ch_set_number_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            def.Number_of_Channels = { 1 '' [1 6] };
            dflt.Number_of_Channels = NelData.General.nChannels;
            dflt = structdlg(def,'Channels Setup',dflt);
            if (dflt.Number_of_Channels ~= NelData.General.nChannels)
                Menu_Ch_closeall_Callback(handles.Menu_Ch_closeall, eventdata, handles, varargin);
            end
            NelData.General.nChannels = dflt.Number_of_Channels;
        end

        % Menu selected function: Menu_Edit_track
        function Menu_Edit_track_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.File_Manager.track.No == 0)
                nelwarn('No Track to Edit. Use ''File->New->Track'' first');
                return;
            end
            new_track(NelData.File_Manager.track.No);
            Update_Unit_Info(app, handles);
        end

        % Menu selected function: Menu_Edit_unit
        function Menu_Edit_unit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.File_Manager.unit.No == 0)
                nelwarn('No Unit to Edit. Use ''File->New->Unit'' first');
                return;
            end
            new_unit(NelData.File_Manager.unit.No);
            Update_Unit_Info(app, handles);
        end

        % Menu selected function: Menu_Exit
        function Menu_Exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Nel_Main_CloseRequestFcn(handles.Nel_Main, eventdata, handles, varargin)
        end

        % Menu selected function: Menu_File_cd
        function Menu_File_cd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            change_data_dir(app, handles,'No');
        end

        % Menu selected function: Menu_Gating
        function Menu_Gating_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.Nel_Main,'SelectionType','open'); % Fake a double click
            Gating_browser_ButtonDownFcn(handles.Gating_browser, eventdata, handles, varargin);
        end

        % Menu selected function: Menu_Log_Error
        function Menu_Log_Error_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ud = get(handles.Error_LB,'Userdata');
            strdlg(ud.log.str(1:ud.log.index), 'Nel Error Log');
        end

        % Menu selected function: Menu_Mix
        function Menu_Mix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.Nel_Main,'SelectionType','open'); % Fake a double click
            Mix_browser_ButtonDownFcn(handles.Mix_browser, eventdata, handles, varargin)
        end

        % Menu selected function: Menu_New_track
        function Menu_New_track_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            new_track;
            Update_Unit_Info(app, handles);
        end

        % Menu selected function: Menu_New_unit
        function Menu_New_unit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            new_unit;
            Update_Unit_Info(app, handles);
        end

        % Menu selected function: Menu_Pulse_Params
        function Menu_Pulse_Params_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            def.enabled = { {'0','{1}'} };
            def.delay = { 100 'ms' [0 5000] };
            def.nPulses = { 4 '' [1 1000]};
            def.interPulse = { 50 'ms' [2 5000] };
            dflt.enabled = NelData.General.Pulse.enabled;
            dflt.delay = NelData.General.Pulse.delay;
            dflt.nPulses = NelData.General.Pulse.nPulses;
            dflt.interPulse = NelData.General.Pulse.interPulse;
            dflt = structdlg(def,'Pulse Setup',dflt);
            NelData.General.Pulse.enabled = dflt.enabled;
            NelData.General.Pulse.delay = dflt.delay;
            NelData.General.Pulse.nPulses = dflt.nPulses;
            NelData.General.Pulse.interPulse = dflt.interPulse;
            Update_PulseInfo(app, handles);
        end

        % Menu selected function: Menu_Save_pic
        function Menu_Save_pic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (isempty(NelData.UnSaved))
                return;
            end
            ud = get(handles.Nel_Main,'Userdata');
            external_run_checkin(app, handles);
            set(ud.run_status_handles,'Visible','on');
            set([handles.Status_Line_info handles.Status_Block_info], 'Visible', 'off');
            set(handles.Status_Block_info,'Text',NelData.DAL.description);
            set(handles.Triggering_popup,'Value',NelData.UnSaved.trig_ind);
            set(handles.Comment,'Text', NelData.UnSaved.comment);
            if (~isempty(NelData.UnSaved.error_strs))
                waitfor(strdlg(NelData.UnSaved.error_strs, 'These Errors messages will be saved with the picture data', [], struct('WindowStyle','modal')));
            end
            Save_collected_data(app, handles, NelData.UnSaved.block_info, NelData.UnSaved.stim_info, NelData.UnSaved.error_strs);
            set(ud.run_status_handles,'Visible','off');
            external_run_checkout(app, handles)
            if (isempty(NelData.UnSaved))
                set(h,'Enable','off');
            end
        end

        % Menu selected function: Menu_Stimulus
        function Menu_Stimulus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.Nel_Main,'SelectionType','open'); % Fake a double click
            Inloop_browser_ButtonDownFcn(handles.Inloop_browser, eventdata, handles, varargin);
        end

        % Menu selected function: MenuToggleAcqParams
        function Menu_Toggle_AcqParams_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.General.EP.show == 0)
                NelData.General.EP.show = 1;
            else
                NelData.General.EP.show = 0;
            end
            Toggle_Show_AcqInfo(app, handles);
        end

        % Menu selected function: MenuTogglePulseParams
        function Menu_Toggle_PulseParams_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.General.Pulse.show == 0)
                NelData.General.Pulse.show = 1;
            else
                NelData.General.Pulse.show = 0;
            end
            Toggle_Show_PulseInfo(app, handles);
        end

        % Menu selected function: Menu_Tools_CAP
        function Menu_Tools_CAP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            external_run_checkin(app, handles);
            h_cap=AEP;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            while (ishandle(h_cap))
                uiwait(h_cap);
                update_nel_title(app, handles);
            end
            update_nel_title(app, handles);
            % h_search = search;
            % delete(h_search);
            external_run_checkout(app, handles);
        end

        % Menu selected function: Menu_Tools_DPOAE
        function Menu_Tools_DPOAE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            external_run_checkin(app, handles);
            
            % Here's where you call the function
            h_dpoae = distortion_product;
            
            if strcmp(NelData.DPOAE.rc,'stopNOSAVE')
                uiwait(h_dpoae);
            end
            update_nel_title(app, handles);
            
            NelData = rmfield(NelData,'DPOAE');
            external_run_checkout(app, handles);
        end

        % Menu selected function: Menu_Tools_WBMEMR
        function Menu_Tools_WBMEMR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            % run NEL's book-keeping for checking out an external program
            % (sets NEL GUI in proper state)
            external_run_checkin(app, handles);  % Check THIS (why full size)
            
            % Here's where you call the function to launch external GUI !!!!!!
            h_wbmemr = wideband_memr;   % no uiwait needed, since no option to leave h_wbmemr existing after complete wideband_memr
            
            update_nel_title(app, handles);  % update pic number in NEL GUI 
            
            external_run_checkout(app, handles);   % Check THIS (why white??)   % close all figures genearted in external program
            NelData = rmfield(NelData,'WBMEMR'); % remove temp field in NelData
        end

        % Menu selected function: Menu_Tools_TC
        function Menu_Tools_TC_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.File_Manager.unit.No == 0)
                nelerror(app, 'Can''t collect data for Unit #0. Open new Unit first');
                return;
            end
            external_run_checkin(app, handles);
            h_tc = tuning_curve;
            if strcmp(NelData.TC.rc,'stopNOSAVE')
                uiwait(h_tc);
            end
            update_nel_title(app, handles);
            Update_Unit_Info(app, handles);
            
            NelData = rmfield(NelData,'TC');
            external_run_checkout(app, handles);
        end

        % Menu selected function: Menu_Tools_calibrateOLD
        function Menu_Tools_calibrateOLD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            external_run_checkin(app, handles);
            currDIR=pwd;
            cd([NelData.General.RootDir 'Nel_matlab\calibrationOLD'])
            h_calib = calibrate;
            while (ishandle(h_calib))
                uiwait(h_calib);
                update_nel_title(app, handles);
            end
            external_run_checkout(app, handles);
            cd(currDIR)
        end

        % Menu selected function: Menu_Tools_calibrate
        function Menu_Tools_calibrate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            external_run_checkin(app, handles);
            h_calib = calibrate;
            while (ishandle(h_calib))
                uiwait(h_calib);
                update_nel_title(app, handles);
            end
            external_run_checkout(app, handles);
        end

        % Menu selected function: Menu_Tools_search
        function Menu_Tools_search_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            external_run_checkin(app, handles);
            search; % AF 5/20/02 - using BJM's new search version
            % h_search = search;
            % delete(h_search);
            external_run_checkout(app, handles);
        end

        % Menu selected function: Menu_nel_tmplts
        function Menu_nel_tmplts_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (isequal(get(h,'Checked'),'off'))
                switch2nel_templates(app, h,handles);
            else
                switch2user_templates(app, h,handles);
            end
        end

        % Menu selected function: Menu_run_stop
        function Menu_run_stop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.run_mode == 0)
                Go_PB_Callback(handles.Go_PB, eventdata, handles, varargin)
            else
                Stop_PB_Callback(handles.Stop_PB, eventdata, handles, varargin)
            end
        end

        % Close request function: Nel_Main
        function Nel_Main_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            if (NelData.run_mode ~= 0)
                errordlg('Can not exit while in ''RUN'' mode');
                return;
            else
                selection = questdlg('Really quit?',...
                    'Nel Close',...
                    'Yes','No','Yes');
                switch selection,
                    case 'Yes',
                        delete(h);
                    case 'No'
                        return
                end
            end
        end

        % Callback function
        function Nel_Main_DeleteFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            act_on_related_handles(app, [],'delete');
            
            user_profile_save(NelData.General.User);
            error_ud = get(handles.Error_LB,'Userdata');
            if (error_ud.log.index > 0)
                error_str = error_ud.log.str(1:error_ud.log.index);
                try
                    fname = [NelData.File_Manager.dirname 'ErrorLog.txt'];
                    fid = fopen(fname,'a');
                    while (fid < 0)
                        title_str = ['Choose a different file name! Can''t write to ''' fname ''''];
                        [fname, dirname] = uiputfile([fileparts(fname) filesep '*.txt'],title_str);
                        fid = fopen(fullfile(dirname,fname),'a');
                    end
                    for i = 1:length(error_str)
                        fprintf(fid,'%s\n',error_str{i});
                    end
                    fclose(fid);
                catch
                    disp('NEL FAILED TO SAVE THE ERROR LOG!!!!!');
                end
            end
            save(NelData.General.save_fname, 'NelData');
        end

        % Key press function: Nel_Main
        function Nel_Main_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            ch = get(h,'CurrentCharacter');
            % double(ch)
            % return
            if (~isempty(ch))
                switch (double(ch))
                    case 13 % Enter
                        if (NelData.run_mode == 3) % In Save DLG
                            SaveData_PB_Callback(handles.SaveData_PB, eventdata, handles, varargin)
                        end
            
                    case 27 % Esc
                        if (NelData.run_mode == 3) % In Save DLG
                            NoSaveData_PB_Callback(handles.NoSaveData_PB, eventdata, handles, varargin)
                        end
            
                    case 19 % CTRL+s
                        if (NelData.run_mode ~= 0)
                            Stop_PB_Callback(handles.Stop_PB, eventdata, handles, varargin)
                        end
            
                    case 18 % CTRL+r
                        if (NelData.run_mode == 0)
                            Go_PB_Callback(handles.Go_PB, eventdata, handles, varargin)
                        end
            
                    case {29,31} % Right and Down arrows
                        Template_next_Callback(handles.Template_next, eventdata, handles, varargin);
            
                    case {28,30} % Left and Up arrows
                        Template_prev_Callback(handles.Template_prev, eventdata, handles, varargin);
            
                end
            end
        end

        % Button pushed function: New_Unit_PB
        function New_Unit_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            new_unit([],[],[],'off');
            Update_Unit_Info(app, handles);
        end

        % Button pushed function: NoSaveData_PB
        function NoSaveData_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.SaveDlg_txt,'UserData',0);
        end

        % Button pushed function: SaveData_PB
        function SaveData_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.SaveDlg_txt,'UserData',1);
        end

        % Button pushed function: Search_PB
        function Search_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_search_Callback(handles.Search_PB, eventdata, handles, varargin);
        end

        % Button pushed function: Stop_PB
        function Stop_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global NelData
            NelData.Stop_request = 1;
        end

        % Value changed function: Template_ShortName
        function Template_ShortName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if (isempty(get(h,'Text')))
                set(handles.Template_ShortName,'BackgroundColor','y');
            else
                set(handles.Template_ShortName,'BackgroundColor','w');
            end
        end

        % Button pushed function: Template_next
        function Template_next_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            val = get(handles.Template_popup,'Value');
            if (val < length(get(handles.Template_popup,'Text')))
                val = val+1;
                set(handles.Template_popup,'Value',val);
                Update_Selected_Template(app, handles);
            else
                ding;
            end
        end

        % Value changed function: Template_popup
        function Template_popup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Update_Selected_Template(app, handles)
        end

        % Button pushed function: Template_prev
        function Template_prev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            val = get(handles.Template_popup,'Value');
            if (val > 1)
                val = val-1;
                set(handles.Template_popup,'Value',val);
                Update_Selected_Template(app, handles)
            else
                ding;
            end
        end

        % Button pushed function: Tuning_Curve_PB
        function Tuning_Curve_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_TC_Callback(handles.Tuning_Curve_PB, eventdata, handles, varargin);
        end

        % Menu selected function: Menu_Tools_AdvOAE
        function Menu_Tools_AdvOAE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            global NelData
            % run NEL's book-keeping for checking out an external program
            % (sets NEL GUI in proper state)
            external_run_checkin(app, handles);  % Check THIS (why full size)
            
            % Here's where you call the function to launch external GUI !!!!!!
            h_AdvOAE = advanced_oae;   % no uiwait needed, since no option to leave h_wbmemr existing after complete wideband_memr
            
            update_nel_title(app, handles);  % update pic number in NEL GUI 
            
            external_run_checkout(app, handles);   % Check THIS (why white??)   % close all figures genearted in external program
            NelData = rmfield(NelData,'AdvOAE'); % remove temp field in NelData
        end

        % Button pushed function: AdvOAE_PB
        function AdvOAE_PB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_AdvOAE_Callback(app, eventdata);
        end

        % Menu selected function: Menu_Tools_FPL
        function Menu_Tools_FPL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            global NelData
            % run NEL's book-keeping for checking out an external program
            % (sets NEL GUI in proper state)
            external_run_checkin(app, handles);  % Check THIS (why full size)
            
            % Here's where you call the function to launch external GUI !!!!!!
            h_FPL = fpl_calibration;   % no uiwait needed, since no option to leave h_wbmemr existing after complete wideband_memr
            
            update_nel_title(app, handles);  % update pic number in NEL GUI 
            
            external_run_checkout(app, handles);  % close all figures genearted in external program
            NelData = rmfield(NelData,'FPL'); % remove temp field in NelData
        end

        % Button pushed function: FPL_PB
        function FPL_PB_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Menu_Tools_FPL_Callback(app, eventdata);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Nel_Main = uifigure('Visible', 'off');
            app.Nel_Main.Name = 'nel';
            app.Nel_Main.CloseRequestFcn = createCallbackFcn(app, @Nel_Main_CloseRequestFcn, true);
            app.Nel_Main.KeyPressFcn = createCallbackFcn(app, @Nel_Main_KeyPressFcn, true);
            app.Nel_Main.Tag = 'Nel_Main';
            app.Menu_File = uimenu(app.Nel_Main);
            app.Menu_File.Text = '&File';
            app.Menu_File.Tag = 'Menu_File';
            app.Menu_new = uimenu(app.Menu_File);
            app.Menu_new.Text = '&New';
            app.Menu_new.Tag = 'Menu_new';
            app.Menu_New_track = uimenu(app.Menu_new);
            app.Menu_New_track.MenuSelectedFcn = createCallbackFcn(app, @Menu_New_track_Callback, true);
            app.Menu_New_track.Text = '&Track';
            app.Menu_New_track.Tag = 'Menu_New_track';
            app.Menu_New_unit = uimenu(app.Menu_new);
            app.Menu_New_unit.MenuSelectedFcn = createCallbackFcn(app, @Menu_New_unit_Callback, true);
            app.Menu_New_unit.Text = '&Unit';
            app.Menu_New_unit.Tag = 'Menu_New_unit';
            app.Menu_Edit = uimenu(app.Menu_File);
            app.Menu_Edit.Text = '&Edit';
            app.Menu_Edit.Tag = 'Menu_Edit';
            app.Menu_Edit_track = uimenu(app.Menu_Edit);
            app.Menu_Edit_track.MenuSelectedFcn = createCallbackFcn(app, @Menu_Edit_track_Callback, true);
            app.Menu_Edit_track.Text = '&Track';
            app.Menu_Edit_track.Tag = 'Menu_Edit_track';
            app.Menu_Edit_unit = uimenu(app.Menu_Edit);
            app.Menu_Edit_unit.MenuSelectedFcn = createCallbackFcn(app, @Menu_Edit_unit_Callback, true);
            app.Menu_Edit_unit.Text = '&Unit';
            app.Menu_Edit_unit.Tag = 'Menu_Edit_unit';
            app.Menu_File_cd = uimenu(app.Menu_File);
            app.Menu_File_cd.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_cd_Callback, true);
            app.Menu_File_cd.Text = 'Change Data &Directory';
            app.Menu_File_cd.Tag = 'Menu_File_cd';
            app.Convert2Mac = uimenu(app.Menu_File);
            app.Convert2Mac.MenuSelectedFcn = createCallbackFcn(app, @Convert2Mac_Callback, true);
            app.Convert2Mac.Separator = 'on';
            app.Convert2Mac.Text = 'Create &Mac-friendly data files';
            app.Convert2Mac.Tag = 'Convert2Mac';
            app.Menu_Save_pic = uimenu(app.Menu_File);
            app.Menu_Save_pic.MenuSelectedFcn = createCallbackFcn(app, @Menu_Save_pic_Callback, true);
            app.Menu_Save_pic.Separator = 'on';
            app.Menu_Save_pic.Text = '&Save Picture';
            app.Menu_Save_pic.Tag = 'Menu_Save_pic';
            app.Menu_Open = uimenu(app.Menu_File);
            app.Menu_Open.Text = '&Open Picture';
            app.Menu_Open.Tag = 'Menu_Open';
            app.Menu_Exit = uimenu(app.Menu_File);
            app.Menu_Exit.MenuSelectedFcn = createCallbackFcn(app, @Menu_Exit_Callback, true);
            app.Menu_Exit.Separator = 'on';
            app.Menu_Exit.Text = 'E&xit';
            app.Menu_Exit.Tag = 'Menu_Exit';
            app.Menu_Block = uimenu(app.Nel_Main);
            app.Menu_Block.Text = '&Block';
            app.Menu_Block.Tag = 'Menu_Block';
            app.Menu_Stimulus = uimenu(app.Menu_Block);
            app.Menu_Stimulus.MenuSelectedFcn = createCallbackFcn(app, @Menu_Stimulus_Callback, true);
            app.Menu_Stimulus.Text = 'Stimulus &Parameters';
            app.Menu_Stimulus.Tag = 'Menu_Stimulus';
            app.Menu_Gating = uimenu(app.Menu_Block);
            app.Menu_Gating.MenuSelectedFcn = createCallbackFcn(app, @Menu_Gating_Callback, true);
            app.Menu_Gating.Text = '&Gating';
            app.Menu_Gating.Tag = 'Menu_Gating';
            app.Menu_Mix = uimenu(app.Menu_Block);
            app.Menu_Mix.MenuSelectedFcn = createCallbackFcn(app, @Menu_Mix_Callback, true);
            app.Menu_Mix.Text = '&Mix';
            app.Menu_Mix.Tag = 'Menu_Mix';
            app.Menu_Acq_Params = uimenu(app.Menu_Block);
            app.Menu_Acq_Params.MenuSelectedFcn = createCallbackFcn(app, @Menu_Acq_Params_Callback, true);
            app.Menu_Acq_Params.Text = '&Acquisition Parameters';
            app.Menu_Acq_Params.Tag = 'Menu_Acq_Params';
            app.Menu_Pulse_Params = uimenu(app.Menu_Block);
            app.Menu_Pulse_Params.MenuSelectedFcn = createCallbackFcn(app, @Menu_Pulse_Params_Callback, true);
            app.Menu_Pulse_Params.Text = 'Pu&lse Parameters';
            app.Menu_Pulse_Params.Tag = 'Menu_Pulse_Params';
            app.Menu_nel_tmplts = uimenu(app.Menu_Block);
            app.Menu_nel_tmplts.MenuSelectedFcn = createCallbackFcn(app, @Menu_nel_tmplts_Callback, true);
            app.Menu_nel_tmplts.Separator = 'on';
            app.Menu_nel_tmplts.Checked = 'on';
            app.Menu_nel_tmplts.Text = 'Use Nel Templates';
            app.Menu_nel_tmplts.Tag = 'Menu_nel_tmplts';
            app.MenuToggleAcqParams = uimenu(app.Menu_Block);
            app.MenuToggleAcqParams.MenuSelectedFcn = createCallbackFcn(app, @Menu_Toggle_AcqParams_Callback, true);
            app.MenuToggleAcqParams.Separator = 'on';
            app.MenuToggleAcqParams.Text = 'Show Acquisition Info';
            app.MenuToggleAcqParams.Tag = 'MenuToggleAcqParams';
            app.MenuTogglePulseParams = uimenu(app.Menu_Block);
            app.MenuTogglePulseParams.MenuSelectedFcn = createCallbackFcn(app, @Menu_Toggle_PulseParams_Callback, true);
            app.MenuTogglePulseParams.Text = 'Show Pulse Info';
            app.MenuTogglePulseParams.Tag = 'MenuTogglePulseParams';
            app.Menu_run_stop = uimenu(app.Nel_Main);
            app.Menu_run_stop.MenuSelectedFcn = createCallbackFcn(app, @Menu_run_stop_Callback, true);
            app.Menu_run_stop.Text = '&Run';
            app.Menu_run_stop.Tag = 'Menu_run_stop';
            app.Menu_Tools = uimenu(app.Nel_Main);
            app.Menu_Tools.Text = '&Tools';
            app.Menu_Tools.Tag = 'Menu_Tools';
            app.Menu_Tools_search = uimenu(app.Menu_Tools);
            app.Menu_Tools_search.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_search_Callback, true);
            app.Menu_Tools_search.Text = '&Search';
            app.Menu_Tools_search.Tag = 'Menu_Tools_search';
            app.Menu_Tools_TC = uimenu(app.Menu_Tools);
            app.Menu_Tools_TC.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_TC_Callback, true);
            app.Menu_Tools_TC.Text = '&Tuning Curve';
            app.Menu_Tools_TC.Tag = 'Menu_Tools_TC';
            app.Menu_Tools_calibrate = uimenu(app.Menu_Tools);
            app.Menu_Tools_calibrate.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_calibrate_Callback, true);
            app.Menu_Tools_calibrate.Separator = 'on';
            app.Menu_Tools_calibrate.Text = 'Calibrate';
            app.Menu_Tools_calibrate.Tag = 'Menu_Tools_calibrate';
            app.Menu_Tools_calibrateOLD = uimenu(app.Menu_Tools);
            app.Menu_Tools_calibrateOLD.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_calibrateOLD_Callback, true);
            app.Menu_Tools_calibrateOLD.Text = 'CalibrateOLD';
            app.Menu_Tools_calibrateOLD.Tag = 'Menu_Tools_calibrateOLD';
            app.Menu_Tools_CAP = uimenu(app.Menu_Tools);
            app.Menu_Tools_CAP.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_CAP_Callback, true);
            app.Menu_Tools_CAP.Separator = 'on';
            app.Menu_Tools_CAP.Text = '&AEP';
            app.Menu_Tools_CAP.Tag = 'Menu_Tools_CAP';
            app.Menu_Tools_DPOAE = uimenu(app.Menu_Tools);
            app.Menu_Tools_DPOAE.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_DPOAE_Callback, true);
            app.Menu_Tools_DPOAE.Text = '&DPOAE';
            app.Menu_Tools_DPOAE.Tag = 'Menu_Tools_DPOAE';
            app.Menu_Tools_WBMEMR = uimenu(app.Menu_Tools);
            app.Menu_Tools_WBMEMR.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_WBMEMR_Callback, true);
            app.Menu_Tools_WBMEMR.Text = 'WBMEMR';
            app.Menu_Tools_WBMEMR.Tag = 'Menu_Tools_WBMEMR';
            app.Menu_Tools_AdvOAE = uimenu(app.Menu_Tools);
            app.Menu_Tools_AdvOAE.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_AdvOAE_Callback, true);
            app.Menu_Tools_AdvOAE.Text = 'AdvOAE';
            app.Menu_Tools_FPL = uimenu(app.Menu_Tools);
            app.Menu_Tools_FPL.MenuSelectedFcn = createCallbackFcn(app, @Menu_Tools_FPL_Callback, true);
            app.Menu_Tools_FPL.Text = 'FPL';
            app.Menu_Tools_FPL.Tag = 'FPL';
            app.Menu_Channels = uimenu(app.Nel_Main);
            app.Menu_Channels.Text = '&Channels';
            app.Menu_Channels.Tag = 'Menu_Channels';
            app.Menu_Ch_set_number = uimenu(app.Menu_Channels);
            app.Menu_Ch_set_number.MenuSelectedFcn = createCallbackFcn(app, @Menu_Ch_set_number_Callback, true);
            app.Menu_Ch_set_number.Text = 'Set #Channels';
            app.Menu_Ch_set_number.Tag = 'Menu_Ch_set_number';
            app.Menu_Ch_bring2front = uimenu(app.Menu_Channels);
            app.Menu_Ch_bring2front.MenuSelectedFcn = createCallbackFcn(app, @Menu_Ch_bring2front_Callback, true);
            app.Menu_Ch_bring2front.Text = 'Bring to &Front';
            app.Menu_Ch_bring2front.Tag = 'Menu_Ch_bring2front';
            app.Menu_Ch_rearrange = uimenu(app.Menu_Channels);
            app.Menu_Ch_rearrange.MenuSelectedFcn = createCallbackFcn(app, @Menu_Ch_rearrange_Callback, true);
            app.Menu_Ch_rearrange.Text = '&Rearrange';
            app.Menu_Ch_rearrange.Tag = 'Menu_Ch_rearrange';
            app.Menu_Ch_closeall = uimenu(app.Menu_Channels);
            app.Menu_Ch_closeall.MenuSelectedFcn = createCallbackFcn(app, @Menu_Ch_closeall_Callback, true);
            app.Menu_Ch_closeall.Separator = 'on';
            app.Menu_Ch_closeall.Text = '&Close all';
            app.Menu_Ch_closeall.Tag = 'Menu_Ch_closeall';
            app.Menu_Log = uimenu(app.Nel_Main);
            app.Menu_Log.Text = '&Log';
            app.Menu_Log.Tag = 'Menu_Log';
            app.Menu_Log_Error = uimenu(app.Menu_Log);
            app.Menu_Log_Error.MenuSelectedFcn = createCallbackFcn(app, @Menu_Log_Error_Callback, true);
            app.Menu_Log_Error.Text = '&Error Log';
            app.Menu_Log_Error.Tag = 'Menu_Log_Error';
            app.Error_LB = uilistbox(app.Nel_Main);
            app.Error_LB.Items = {''};
            app.Error_LB.Multiselect = 'on';
            app.Error_LB.Tag = 'Error_LB';
            app.Error_LB.Enable = 'off';
            app.Error_LB.Visible = 'off';
            app.Error_LB.Tooltip = 'Error messages';
            app.Error_LB.Value = {};
            app.PulseInfoStatic = uilabel(app.Nel_Main);
            app.PulseInfoStatic.Tag = 'PulseInfoStatic';
            app.PulseInfoStatic.Enable = 'off';
            app.PulseInfoStatic.Text = {'Pulse Output:'; 'Enabled?'; 'Delay (msec):'; '# of pulses:'; 'Interpulse (msec):'};
            app.Triggering_title = uilabel(app.Nel_Main);
            app.Triggering_title.Tag = 'Triggering_title';
            app.Triggering_title.Visible = 'off';
            app.Triggering_title.Text = 'Trigger';
            app.Inloop_title = uilabel(app.Nel_Main);
            app.Inloop_title.Tag = 'Inloop_title';
            app.Inloop_title.Text = 'Stimulus Parameters';
            app.Template_title = uilabel(app.Nel_Main);
            app.Template_title.Tag = 'Template_title';
            app.Template_title.Text = 'Block Definitions';
            app.Template_popup = uidropdown(app.Nel_Main);
            app.Template_popup.Items = {'Popup Menu'};
            app.Template_popup.ValueChangedFcn = createCallbackFcn(app, @Template_popup_Callback, true);
            app.Template_popup.Tag = 'Template_popup';
            app.Template_popup.Value = 'Popup Menu';
            app.Template_next = uibutton(app.Nel_Main, 'push');
            app.Template_next.ButtonPushedFcn = createCallbackFcn(app, @Template_next_Callback, true);
            app.Template_next.Tag = 'Template_next';
            app.Template_next.Text = '»';
            app.Template_prev = uibutton(app.Nel_Main, 'push');
            app.Template_prev.ButtonPushedFcn = createCallbackFcn(app, @Template_prev_Callback, true);
            app.Template_prev.Tag = 'Template_prev';
            app.Template_prev.Text = '«';
            app.Gating_browser = uilistbox(app.Nel_Main);
            app.Gating_browser.Items = {};
            app.Gating_browser.Multiselect = 'on';
            app.Gating_browser.Tag = 'Gating_browser';
            app.Gating_browser.Enable = 'off';
            app.Gating_browser.Value = {};
            app.Gating_title = uilabel(app.Nel_Main);
            app.Gating_title.Tag = 'Gating_title';
            app.Gating_title.Text = 'Gating';
            app.Mix_title = uilabel(app.Nel_Main);
            app.Mix_title.Tag = 'Mix_title';
            app.Mix_title.Text = 'Mix';
            app.Template_ShortName = uieditfield(app.Nel_Main, 'text');
            app.Template_ShortName.ValueChangedFcn = createCallbackFcn(app, @Template_ShortName_Callback, true);
            app.Template_ShortName.Tag = 'Template_ShortName';
            app.Template_ShortName.Enable = 'off';
            app.Template_ShorName_LBL = uilabel(app.Nel_Main);
            app.Template_ShorName_LBL.Tag = 'Template_ShorName_LBL';
            app.Template_ShorName_LBL.Text = 'Short name:';
            app.Mix_browser = uilistbox(app.Nel_Main);
            app.Mix_browser.Items = {};
            app.Mix_browser.Multiselect = 'on';
            app.Mix_browser.Tag = 'Mix_browser';
            app.Mix_browser.Enable = 'off';
            app.Mix_browser.Value = {};
            app.Inloop_browser = uilistbox(app.Nel_Main);
            app.Inloop_browser.Items = {};
            app.Inloop_browser.Multiselect = 'on';
            app.Inloop_browser.Tag = 'Inloop_browser';
            app.Inloop_browser.Enable = 'off';
            app.Inloop_browser.Value = {};
            app.ShortCuts_BG_dummy = uibutton(app.Nel_Main, 'push');
            app.ShortCuts_BG_dummy.Tag = 'ShortCuts_BG_dummy';
            app.ShortCuts_BG_dummy.Enable = 'off';
            app.ShortCuts_BG_dummy.Text = '';
            app.Go_PB = uibutton(app.Nel_Main, 'push');
            app.Go_PB.ButtonPushedFcn = createCallbackFcn(app, @Go_PB_Callback, true);
            app.Go_PB.Tag = 'Go_PB';
            app.Go_PB.Icon = 'Go_PB_image.png';
            app.Go_PB.Tooltip = 'Go';
            app.Go_PB.Text = '';
            app.Stop_PB = uibutton(app.Nel_Main, 'push');
            app.Stop_PB.ButtonPushedFcn = createCallbackFcn(app, @Stop_PB_Callback, true);
            app.Stop_PB.Tag = 'Stop_PB';
            app.Stop_PB.Icon = 'Stop_PB_image.png';
            app.Stop_PB.Enable = 'off';
            app.Stop_PB.Tooltip = 'Stop';
            app.Stop_PB.Text = '';
            app.Unit_info_lbl = uilabel(app.Nel_Main);
            app.Unit_info_lbl.Tag = 'Unit_info_lbl';
            app.Unit_info_lbl.Enable = 'off';
            app.Unit_info_lbl.Text = {'Track:'; 'Unit:'; 'BF (kHz):'; 'Th (dB Attn):'; 'SR: '};
            app.Comment_title = uilabel(app.Nel_Main);
            app.Comment_title.Tag = 'Comment_title';
            app.Comment_title.Visible = 'off';
            app.Comment_title.Text = 'Comment';
            app.Comment = uitextarea(app.Nel_Main);
            app.Comment.Tag = 'Comment';
            app.Comment.Visible = 'off';
            app.Triggering_popup = uidropdown(app.Nel_Main);
            app.Triggering_popup.Items = {'', 'Poor', 'Fair', 'Good', 'Excellent'};
            app.Triggering_popup.Tag = 'Triggering_popup';
            app.Triggering_popup.Visible = 'off';
            app.Triggering_popup.Value = '';
            app.Unit_Info = uilabel(app.Nel_Main);
            app.Unit_Info.Tag = 'Unit_Info';
            app.Unit_Info.Enable = 'off';
            app.Unit_Info.Tooltip = 'Double click to edit';
            app.Unit_Info.Text = {'1'; '01'; '2.34'; '34'; 'NaN'};
            app.Status_Block_info = uilabel(app.Nel_Main);
            app.Status_Block_info.Tag = 'Status_Block_info';
            app.Status_Block_info.Enable = 'off';
            app.Status_Block_info.Visible = 'off';
            app.Status_Block_info.Text = '';
            app.Status_Line_info = uilabel(app.Nel_Main);
            app.Status_Line_info.Tag = 'Status_Line_info';
            app.Status_Line_info.Enable = 'off';
            app.Status_Line_info.Visible = 'off';
            app.Status_Line_info.Text = '';
            app.SaveDlg_Frame = uibutton(app.Nel_Main, 'push');
            app.SaveDlg_Frame.Tag = 'SaveDlg_Frame';
            app.SaveDlg_Frame.Enable = 'off';
            app.SaveDlg_Frame.Visible = 'off';
            app.SaveDlg_Frame.Text = '';
            app.NoSaveData_PB = uibutton(app.Nel_Main, 'push');
            app.NoSaveData_PB.ButtonPushedFcn = createCallbackFcn(app, @NoSaveData_PB_Callback, true);
            app.NoSaveData_PB.Tag = 'NoSaveData_PB';
            app.NoSaveData_PB.Icon = 'NoSaveData_PB_image.png';
            app.NoSaveData_PB.Visible = 'off';
            app.NoSaveData_PB.Tooltip = 'Don''t Save';
            app.NoSaveData_PB.Text = '';
            app.SaveDlg_txt = uilabel(app.Nel_Main);
            app.SaveDlg_txt.Tag = 'SaveDlg_txt';
            app.SaveDlg_txt.Visible = 'off';
            app.SaveDlg_txt.Text = 'Save?';
            app.New_Unit_PB = uibutton(app.Nel_Main, 'push');
            app.New_Unit_PB.ButtonPushedFcn = createCallbackFcn(app, @New_Unit_PB_Callback, true);
            app.New_Unit_PB.Tag = 'New_Unit_PB';
            app.New_Unit_PB.Icon = 'New_Unit_PB_image.png';
            app.New_Unit_PB.Tooltip = 'New Unit';
            app.New_Unit_PB.Text = '';
            app.Search_PB = uibutton(app.Nel_Main, 'push');
            app.Search_PB.ButtonPushedFcn = createCallbackFcn(app, @Search_PB_Callback, true);
            app.Search_PB.Tag = 'Search_PB';
            app.Search_PB.Icon = 'Search_PB_image.png';
            app.Search_PB.Tooltip = 'Search';
            app.Search_PB.Text = '';
            app.Tuning_Curve_PB = uibutton(app.Nel_Main, 'push');
            app.Tuning_Curve_PB.ButtonPushedFcn = createCallbackFcn(app, @Tuning_Curve_PB_Callback, true);
            app.Tuning_Curve_PB.Tag = 'Tuning_Curve_PB';
            app.Tuning_Curve_PB.Icon = 'Tuning_Curve_PB_image.png';
            app.Tuning_Curve_PB.Tooltip = 'Tuning Curve';
            app.Tuning_Curve_PB.Text = '';
            app.AcqInfoStatic = uilabel(app.Nel_Main);
            app.AcqInfoStatic.Tag = 'AcqInfoStatic';
            app.AcqInfoStatic.Enable = 'off';
            app.AcqInfoStatic.Text = {'A/D Acquisition:'; 'Record?'; 'Start (msec):'; 'Duration (msec):'; 'Save all trials?'; '   Decimate?'; '   Factor:'};
            app.AcqInfo = uilabel(app.Nel_Main);
            app.AcqInfo.Tag = 'AcqInfo';
            app.AcqInfo.Enable = 'off';
            app.AcqInfo.Tooltip = 'Double click to edit';
            app.AcqInfo.Text = '';
            app.PulseInfo = uilabel(app.Nel_Main);
            app.PulseInfo.Tag = 'PulseInfo';
            app.PulseInfo.Enable = 'off';
            app.PulseInfo.Tooltip = 'Double click to edit';
            app.PulseInfo.Text = '';
            app.CAP_PB = uibutton(app.Nel_Main, 'push');
            app.CAP_PB.ButtonPushedFcn = createCallbackFcn(app, @CAP_PB_Callback, true);
            app.CAP_PB.Tag = 'CAP_PB';
            app.CAP_PB.Icon = 'AEP_PB_image.png';
            app.CAP_PB.Tooltip = {'AEP'};
            app.CAP_PB.Text = '';
            app.DPOAE_PB = uibutton(app.Nel_Main, 'push');
            app.DPOAE_PB.ButtonPushedFcn = createCallbackFcn(app, @DPOAE_PB_Callback, true);
            app.DPOAE_PB.Tag = 'DPOAE_PB';
            app.DPOAE_PB.Icon = 'DPOAE_PB_image.png';
            app.DPOAE_PB.Tooltip = 'DPOAE';
            app.DPOAE_PB.Text = '';
            app.Inhibit_PB = uibutton(app.Nel_Main, 'push');
            app.Inhibit_PB.ButtonPushedFcn = createCallbackFcn(app, @Inhibit_PB_Callback, true);
            app.Inhibit_PB.Tag = 'Inhibit_PB';
            app.Inhibit_PB.Icon = 'Inhibit_PB_image.png';
            app.Inhibit_PB.Tooltip = 'Inhibit';
            app.Inhibit_PB.Text = '';
            app.SaveData_PB = uibutton(app.Nel_Main, 'push');
            app.SaveData_PB.ButtonPushedFcn = createCallbackFcn(app, @SaveData_PB_Callback, true);
            app.SaveData_PB.Tag = 'SaveData_PB';
            app.SaveData_PB.Icon = 'SaveData_PB_image.png';
            app.SaveData_PB.Visible = 'off';
            app.SaveData_PB.Tooltip = 'Save';
            app.SaveData_PB.Text = '';
            app.WBMEMR_PB = uibutton(app.Nel_Main, 'push');
            app.WBMEMR_PB.ButtonPushedFcn = createCallbackFcn(app, @WBMEMR_PB_Callback, true);
            app.WBMEMR_PB.Tag = 'MEMR_PB';
            app.WBMEMR_PB.Icon = 'WBMEMR_PB_image.png';
            app.WBMEMR_PB.Tooltip = {'MEMR'};
            app.WBMEMR_PB.Text = '';
            app.AdvOAE_PB = uibutton(app.Nel_Main, 'push');
            app.AdvOAE_PB.ButtonPushedFcn = createCallbackFcn(app, @AdvOAE_PB_Callback, true);
            app.AdvOAE_PB.Tag = 'AdvOAE_PB';
            app.AdvOAE_PB.Icon = 'AdvOAE_PB_image.png';
            app.AdvOAE_PB.Tooltip = {'AdvOAE'};
            app.AdvOAE_PB.Text = '';
            app.FPL_PB = uibutton(app.Nel_Main, 'push');
            app.FPL_PB.ButtonPushedFcn = createCallbackFcn(app, @FPL_PB_Callback, true);
            app.FPL_PB.Tag = 'FPL_PB';
            app.FPL_PB.Icon = 'FPL_PB_image.png';
            app.FPL_PB.Tooltip = {'FPL'};
            app.FPL_PB.Text = '';
            % Show the figure after all components are created
            app.Nel_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nel_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Nel_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Nel_Main)
        end
    end
end